classdef milkweed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ChooseRuntoLoadLabel  matlab.ui.control.Label
        RunSelect             matlab.ui.control.DropDown
        PlotArea              matlab.ui.control.UIAxes
        LoadButton            matlab.ui.control.Button
        PlotTypeLabel         matlab.ui.control.Label
        PlotSelect            matlab.ui.control.DropDown
        SummaryTextAreaLabel  matlab.ui.control.Label
        SummaryTextArea       matlab.ui.control.TextArea
        PlotButton            matlab.ui.control.Button
    end

    
    methods (Access = private)
        

    end
            
    %% Helper Variables
    properties (Access = public)
        run_data %struct containing data from monte carlo runs
    end
    
    %% Helper Functions
    methods (Access = public)
        
        %Function combs previously stored runs to load them all for the
        %app.
        function runs = run_lister(app)
            cd run_storage
            temp = dir('*.mat');
            [num_stored,~] = size(temp);
            
            
            runs = cell(int8(num_stored + 1), 1);
            runs{1} = '---';
            for i = 1:num_stored
                parts = strsplit(temp(i).name, '.');
                runs{i+1} = parts{1};
            end
            cd ../
        end
        
        %Function loads data and ties it to app, that way data doesn't need
        %to be re-loaded for every op.
        function dump = load_run(app, run_name)
            run_name = strcat(run_name, '.mat');
            cd run_storage;
            dump = load(run_name);
            file_info = dir(run_name);
            dump.date = file_info.date;
            
            %Get the heck out of there
            cd ../
        end
        
        %Quick and easy function to pull sample size
        function sample_size = run_size(app)
            [sample_size,~] = size(app.run_data.initial_rates);
        end
        
        %Function will give the run time statistics if that given run
        function [mu_time, sigma_time] = time_stats(app)
            good_times = app.run_data.total_time(terminal_rate_check(app));
            mu_time = mean(good_times);
            sigma_time = std(good_times);
        end
        
        function in_spec_tf = terminal_rate_check(app)
            terminal_rates = app.run_data.terminal_rates;
            threshold = deg2rad(1); %Given threshold of 1 deg/s per axis
            
            %logical matrix to comb through the rates output by the sim and
            %check they are under our threshold.
            in_or_out_tf = zeros(size(terminal_rates));
            for i = 1:3
                in_or_out_tf(:,i) = abs(terminal_rates(:,i)) < threshold;
            end
            in_spec_tf = (in_or_out_tf(:,1)) & (in_or_out_tf(:,2)) & (in_or_out_tf(:,3));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            runs = run_lister(app);
            app.RunSelect.Items = runs;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            run_name = convertCharsToStrings(app.RunSelect.Value);
            load = load_run(app, run_name);
            
            %Break out the struct - one less layer to dig through
            app.run_data.initial_rates = load.data_store.initial_rates;
            app.run_data.terminal_rates = load.data_store.terminal_rates;
            app.run_data.total_time = load.data_store.total_time;
            app.run_data.date = load.date;
            app.run_data.margin = load.data_store.margin;
            
            %Generate Stats for Summary Box
            sample_size = run_size(app);
            in_spec_tf = terminal_rate_check(app);
            num_pass = sum(in_spec_tf); %sum of logical array will tell us how many made it
            pass_rate = (num_pass / sample_size) * 100;
            [average_time, stdev_time] = time_stats(app);
            
            %Print out to Summary
            app.SummaryTextArea.Value{1} = convertStringsToChars(sprintf("Run Date: %s", app.run_data.date));
            app.SummaryTextArea.Value{2} = convertStringsToChars(sprintf("Sample Size: %d runs", sample_size));
            app.SummaryTextArea.Value{3} = convertStringsToChars(sprintf("Passing Rate: %.2g Percent", pass_rate));
            app.SummaryTextArea.Value{4} = convertStringsToChars(sprintf("Margin: %d Percent", margin - 1));
            app.SummaryTextArea.Value{5} = convertStringsToChars(sprintf("Detumble Time Average of Passing Runs: %4.2f s", average_time));
            app.SummaryTextArea.Value{6} = convertStringsToChars(sprintf("Detumble Time St. Dev of Passing Runs: %4.2f s", stdev_time));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseRuntoLoadLabel = uilabel(app.UIFigure);
            app.ChooseRuntoLoadLabel.Text = {'Choose Run to Load'; ''};
            app.RunSelect = uidropdown(app.UIFigure);
            app.RunSelect.Items = {'---'};
            app.RunSelect.Value = '---';
            app.PlotArea = uiaxes(app.UIFigure);
            title(app.PlotArea, '')
            xlabel(app.PlotArea, 'Initial Rates [deg/s]')
            ylabel(app.PlotArea, {'Final Rates [deg/s]'; ''})
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotTypeLabel = uilabel(app.UIFigure);
            app.PlotTypeLabel.Text = {'Plot Type'; ''};
            app.PlotSelect = uidropdown(app.UIFigure);
            app.PlotSelect.Items = {'---', 'All Rates', 'omega_x', 'omega_y', 'omega_z', 'Rates in Excess'};
            app.PlotSelect.Value = '---';
            app.SummaryTextAreaLabel = uilabel(app.UIFigure);
            app.SummaryTextAreaLabel.Text = {'Summary'; ''};
            app.SummaryTextArea = uitextarea(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = milkweed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end